classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ModeButtonGroup           matlab.ui.container.ButtonGroup
        AMButton                  matlab.ui.control.RadioButton
        FMButton                  matlab.ui.control.RadioButton
        FilePathEditFieldLabel    matlab.ui.control.Label
        FilePathEditField         matlab.ui.control.EditField
        MessageButtonGroup        matlab.ui.container.ButtonGroup
        HarmonicButton_2          matlab.ui.control.RadioButton
        FileButton                matlab.ui.control.RadioButton
        HarmonicHzEditFieldLabel  matlab.ui.control.Label
        HarmonicHzEditField       matlab.ui.control.NumericEditField
        ModulationandDemodulationSimulationLabel  matlab.ui.control.Label
        CarrierkHzEditFieldLabel  matlab.ui.control.Label
        CarrierkHzEditField       matlab.ui.control.NumericEditField
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t = 0;
        dt = 1e-7;
    end
    
    methods (Access = private)
        
        function f_modulate(app, singal_modulated)
            y = sin(app.t * app.CarrierkHzEditField.Value*1e3*2*pi);
            axis(app.UIAxes, [app.t-20e-6, app.t, -1.1, 1.1])
            addpoints(singal_modulated, app.t, y);
        
            drawnow limitrate;
            pause(0.01);
            
            app.t = app.t + app.dt;
        end
        
        function app = loop(app, singal_modulated)
            while true
                f_modulate(app, singal_modulated);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            singal_modulated = animatedline(app.UIAxes);
            hold(app.UIAxes, 'on')
            loop(app, singal_modulated);
        end

        % Selection changed function: MessageButtonGroup
        function radio_message_callback(app, event)
            selectedButton = app.MessageButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Modulated Signal')
            xlabel(app.UIAxes, 'time (us)')
            ylabel(app.UIAxes, 'amplitude (V)')
            app.UIAxes.Tag = 'axes_modulated';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.AMButton = uiradiobutton(app.ModeButtonGroup);
            app.AMButton.Text = 'AM';
            app.AMButton.Value = true;
            app.FMButton = uiradiobutton(app.ModeButtonGroup);
            app.FMButton.Text = 'FM';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Tag = 'edit_file_path';
            app.MessageButtonGroup = uibuttongroup(app.UIFigure);
            app.MessageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @radio_message_callback, true);
            app.MessageButtonGroup.Title = 'Message';
            app.MessageButtonGroup.Tag = 'radio_message';
            app.HarmonicButton_2 = uiradiobutton(app.MessageButtonGroup);
            app.HarmonicButton_2.Text = 'Harmonic';
            app.HarmonicButton_2.Value = true;
            app.FileButton = uiradiobutton(app.MessageButtonGroup);
            app.FileButton.Text = 'File';
            app.HarmonicHzEditFieldLabel = uilabel(app.UIFigure);
            app.HarmonicHzEditFieldLabel.Text = 'Harmonic (Hz)';
            app.HarmonicHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HarmonicHzEditField.Tag = 'edit_harmonic';
            app.HarmonicHzEditField.Value = 500;
            app.ModulationandDemodulationSimulationLabel = uilabel(app.UIFigure);
            app.ModulationandDemodulationSimulationLabel.Text = 'Modulation and Demodulation Simulation';
            app.CarrierkHzEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierkHzEditFieldLabel.Text = 'Carrier (kHz)';
            app.CarrierkHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierkHzEditField.Tag = 'edit_carrier';
            app.CarrierkHzEditField.Value = 300;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Demodulated Signal')
            xlabel(app.UIAxes_2, 'time (us)')
            ylabel(app.UIAxes_2, 'amplitude (V)')
            app.UIAxes_2.Tag = 'axes_demodulated';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end